classdef YI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        presence       matlab.ui.control.UIAxes
        future         matlab.ui.control.UIAxes
        Lamp_Yao_1     matlab.ui.control.Lamp
        Lamp_Yao_2     matlab.ui.control.Lamp
        Lamp_Yao_3     matlab.ui.control.Lamp
        Lamp_Yao_4     matlab.ui.control.Lamp
        Lamp_Yao_5     matlab.ui.control.Lamp
        Lamp_Yao_6     matlab.ui.control.Lamp
        Panel          matlab.ui.container.Panel
        Label          matlab.ui.control.Label
        Slider_Bian_1  matlab.ui.control.Slider
        Label_2        matlab.ui.control.Label
        Slider_Bian_2  matlab.ui.control.Slider
        Label_3        matlab.ui.control.Label
        Slider_Bian_3  matlab.ui.control.Slider
        Lamp_Bian_1    matlab.ui.control.Lamp
        Lamp_Bian_2    matlab.ui.control.Lamp
        Lamp_Bian_3    matlab.ui.control.Lamp
        Start          matlab.ui.control.Button
        End            matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp_Yao_1.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_2.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_3.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_4.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_5.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_6.Color = [0.9 0.9 0.9];
            
            app.Lamp_Bian_1.Color = [0.9 0.9 0.9];
            app.Lamp_Bian_2.Color = [0.9 0.9 0.9];
            app.Lamp_Bian_3.Color = [0.9 0.9 0.9];
            
            app.Slider_Bian_1.Enable = 'off';
            app.Slider_Bian_2.Enable = 'off';
            app.Slider_Bian_3.Enable = 'off';
            
            app.Slider_Bian_1.MajorTicks = [];
            app.Slider_Bian_1.MinorTicks = [];
            app.Slider_Bian_2.MajorTicks = [];
            app.Slider_Bian_2.MinorTicks = [];
            app.Slider_Bian_3.MajorTicks = [];
            app.Slider_Bian_3.MinorTicks = [];
            
            
            app.presence.Box = 'on';
            app.presence.XTick = [];
            app.presence.YTick = [];
            app.presence.XLabel.String = [];
            app.presence.YLabel.String = [];
            
            app.future.Box = 'on';
            app.future.XTick = [];
            app.future.YTick = [];
            app.future.XLabel.String = [];
            app.future.YLabel.String = [];
       
%             axis(app.presence, 'off');
%             axis(app.future, 'off');
            
            yao_num = 0;
            setappdata(0, 'yao_num', yao_num);
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            app.Lamp_Yao_1.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_2.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_3.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_4.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_5.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_6.Color = [0.9 0.9 0.9];
            
            app.Lamp_Bian_1.Color = [0.9 0.9 0.9];
            app.Lamp_Bian_2.Color = [0.9 0.9 0.9];
            app.Lamp_Bian_3.Color = [0.9 0.9 0.9];
            
            app.Slider_Bian_1.Enable = 'on';
            app.Slider_Bian_1.Limits = [1 49];
            app.Lamp_Bian_1.Color = [0.93 0.69 0.13];
            
            G = cell(8,8);
            G(1,:) = {'乾','履','同人','无妄','姤','讼','遁','否'};
            G(2,:) = {'夬','泽','革','随','大过','困','咸','萃'};
            G(3,:) = {'大有','睽','离','噬嗑','鼎','未济','旅','晋'};
            G(4,:) = {'大壮','归妹','丰','震','恒','解','小过','豫'};
            G(5,:) = {'小畜','中孚','家人','益','巽','涣','渐','观'};
            G(6,:) = {'需','节','既济','屯','井','坎','蹇','比'};
            G(7,:) = {'大畜','损','贲','颐','蛊','蒙','艮','剥'};
            G(8,:) = {'泰','临','明夷','复','升','师','谦','坤'};
            setappdata(0, 'G', G);
            
            app.Start.Enable = 'off';
        end

        % Value changed function: Slider_Bian_1
        function Slider_Bian_1ValueChanged(app, event)
            value = app.Slider_Bian_1.Value;
            value = round(value);
            init = 1:49;
            A = init(1:value);
            B = init(value+1:end);
            alpha = rand;
            if alpha < 0.5
                A = A(1:end-1);
            else
                B = B(1:end-1);
            end

            rem_A = rem(length(A), 4);
            rem_B = rem(length(B), 4);
            if rem_A == 0
                rem_A = 4;
            end
            if rem_B == 0
                rem_B = 4;
            end
            
            b_1 = rem_A+ rem_B + 1;
            
            setappdata(0, 'b_1', b_1);
            app.Lamp_Bian_1.Color = 'g';
            app.Lamp_Bian_2.Color = [0.93 0.69 0.13];
            app.Slider_Bian_2.Enable = 'on';
            app.Slider_Bian_2.Limits = [1 b_1];
            
            yao_num = getappdata(0, 'yao_num') + 1;
            Lamp_sign = ['Lamp_Yao_',num2str(yao_num)];
            app.(Lamp_sign).Color = [0.93 0.69 0.13];
            setappdata(0, 'yao_num', yao_num);
        end

        % Value changed function: Slider_Bian_2
        function Slider_Bian_2ValueChanged(app, event)
            value = app.Slider_Bian_2.Value;
            value = round(value);
            b_1 = getappdata(0, 'b_1');
            init_2 = 1:49-b_1;
            A = init_2(1:value);
            B = init_2(value+1:end);
            alpha = rand;
            if alpha < 0.5
                A = A(1:end-1);
            else
                B = B(1:end-1);
            end
            rem_A = rem(length(A), 4);
            rem_B = rem(length(B), 4);
            if rem_A == 0
                rem_A = 4;
            end
            if rem_B == 0
                rem_B = 4;
            end
            
            b_2 = rem_A+ rem_B + 1;
            
            setappdata(0, 'b_2', b_2);
            app.Lamp_Bian_2.Color = 'g';
            app.Lamp_Bian_3.Color = [0.93 0.69 0.13];
            app.Slider_Bian_3.Enable = 'on';
            app.Slider_Bian_3.Limits = [1 b_2];
        end

        % Value changed function: Slider_Bian_3
        function Slider_Bian_3ValueChanged(app, event)
            value = app.Slider_Bian_3.Value;
            value = round(value);
            b_1 = getappdata(0, 'b_1');
            b_2 = getappdata(0, 'b_2');
            init_3 = 1:49-b_1-b_2;
            A = init_3(1:value);
            B = init_3(value+1:end);
            alpha = rand;
            if alpha < 0.5
                A = A(1:end-1);
            else
                B = B(1:end-1);
            end
            rem_A = rem(length(A), 4);
            rem_B = rem(length(B), 4);
            if rem_A == 0
                rem_A = 4;
            end
            if rem_B == 0
                rem_B = 4;
            end
            
            b_3 = rem_A+ rem_B + 1;
            
            setappdata(0, 'b_3', b_3);
            app.Lamp_Bian_3.Color = 'g';
            
            rest = 49 - (b_1 + b_2 + b_3);
            yao = floor(rest/4);
            
            yao_num = getappdata(0, 'yao_num');
            color = {'k','r'};
            switch yao
                case 6                                                   % 老阴
                    yao_bin = 0;
                    bian_yao = 1;
                    
                    x1 = -5:-2;
                    y1 = ones(1,length(x1));
                    x2 = 2:5;
                    y2 = ones(1,length(x2));
                    
                    yao_color = color{bian_yao + 1};
                    hold(app.presence, 'on');
                    plot(app.presence, x1, yao_num*y1, yao_color, ...
                        x2, yao_num*y2, yao_color, 'LineWidth', 2);
                    app.presence.XLim = [-10 10];
                    app.presence.YLim = [0 7];
                case 7                                                   % 少阳                                       
                    yao_bin = 1;
                    bian_yao = 0;
                    
                    x = -5:5;
                    y = ones(1,length(x));
                    
                    yao_color = color{bian_yao + 1};
                    hold(app.presence, 'on');
                    plot(app.presence, x, yao_num*y, yao_color, 'LineWidth', 2);
                    app.presence.XLim = [-10 10];
                    app.presence.YLim = [0 7];
                case 8                                                   % 少阴
                    yao_bin = 0;
                    bian_yao = 0;
                    
                    x1 = -5:-2;
                    y1 = ones(1,length(x1));
                    x2 = 2:5;
                    y2 = ones(1,length(x2));
                    
                    yao_color = color{bian_yao + 1};
                    hold(app.presence, 'on');
                    plot(app.presence, x1, yao_num*y1, yao_color, ...
                        x2, yao_num*y2, yao_color, 'LineWidth', 2);
                    app.presence.XLim = [-10 10];
                    app.presence.YLim = [0 7];
                case 9                                                   % 老阳
                    yao_bin = 1;
                    bian_yao = 1;
                    
                    x = -5:5;
                    y = ones(1,length(x));
                    
                    yao_color = color{bian_yao + 1};
                    hold(app.presence, 'on');
                    plot(app.presence, x, yao_num*y, yao_color, 'LineWidth', 2);
                    app.presence.XLim = [-10 10];
                    app.presence.YLim = [0 7];
                otherwise
                    msgbox('你他娘的算错了');
            end
            
            if yao_num == 1
                gua_bin(yao_num) = yao_bin;
                bian_bin(yao_num) = bian_yao;
            else
                gua_bin = getappdata(0, 'gua_bin');
                gua_bin(yao_num) = yao_bin;
                bian_bin = getappdata(0, 'bian_bin');
                bian_bin(yao_num) = bian_yao;
            end
            
            Lamp_sign = ['Lamp_Yao_',num2str(yao_num)];
            app.(Lamp_sign).Color = 'g';
            app.Lamp_Bian_1.Color = [0.93 0.69 0.13];
            app.Lamp_Bian_2.Color = [0.93 0.69 0.13];
            app.Lamp_Bian_3.Color = [0.93 0.69 0.13];
            app.Slider_Bian_1.Limits = [1 49];
            app.Slider_Bian_1.Value = 1;
            app.Slider_Bian_2.Limits = [1 50];
            app.Slider_Bian_2.Value = 1;
            app.Slider_Bian_3.Limits = [1 50];
            app.Slider_Bian_3.Value = 1;
            
            if yao_num == 6
                G = getappdata(0, 'G');
                gua_u = gua_bin(4:6);
                gua_l = gua_bin(1:3);
                idx_u = 8 - bin2dec(num2str(gua_u));
                idx_l = 8 - bin2dec(num2str(gua_l));
                name = G{idx_u,idx_l};
                app.presence.XLabel.String = name;
                
                bian_pos = bian_bin == 1;
                gua_future = abs(bian_pos - gua_bin);
                yao_num = 0;
                
                hold(app.future, 'on');
                for i = 1:length(gua_future)
                    yao_num = yao_num + 1;
                    if gua_future(i) == 1
                        x = -5:5;
                        y = yao_num*ones(1,length(x));
                        plot(app.future, x, y, 'k', 'LineWidth', 2);
                    else
                        x1 = -5:-2;
                        y1 = yao_num*ones(1,length(x1));
                        x2 = 2:5;
                        y2 = yao_num*ones(1,length(x2));
                        plot(app.future, x1, y1, 'k', ...
                            x2, y2, 'k', 'LineWidth', 2);
                    end
                end
                hold(app.future, 'off');
                app.future.XLim = [-10 10];
                app.future.YLim = [0 7];
                gua_future_u = gua_future(4:6);
                gua_future_l = gua_future(1:3);
                idx_future_u = 8 - bin2dec(num2str(gua_future_u));
                idx_future_l = 8 - bin2dec(num2str(gua_future_l));
                name_future = G(idx_future_u, idx_future_l);
                app.future.XLabel.String = name_future;
            end
            
            setappdata(0, 'yao_num', yao_num);
            setappdata(0, 'gua_bin', gua_bin);
            setappdata(0, 'bian_bin', bian_bin);
        end

        % Button pushed function: End
        function EndPushed(app, event)
            app.Lamp_Yao_1.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_2.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_3.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_4.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_5.Color = [0.9 0.9 0.9];
            app.Lamp_Yao_6.Color = [0.9 0.9 0.9];
            
            app.Lamp_Bian_1.Color = [0.9 0.9 0.9];
            app.Lamp_Bian_2.Color = [0.9 0.9 0.9];
            app.Lamp_Bian_3.Color = [0.9 0.9 0.9];
            
            app.Slider_Bian_1.Enable = 'off';
            app.Slider_Bian_2.Enable = 'off';
            app.Slider_Bian_3.Enable = 'off';
            
            app.Slider_Bian_1.MajorTicks = [];
            app.Slider_Bian_1.MinorTicks = [];
            app.Slider_Bian_2.MajorTicks = [];
            app.Slider_Bian_2.MinorTicks = [];
            app.Slider_Bian_3.MajorTicks = [];
            app.Slider_Bian_3.MinorTicks = [];
            
            
            app.presence.Box = 'on';
            app.presence.XTick = [];
            app.presence.YTick = [];
            app.presence.XLabel.String = [];
            app.presence.YLabel.String = [];
            
            app.future.Box = 'on';
            app.future.XTick = [];
            app.future.YTick = [];
            app.future.XLabel.String = [];
            app.future.YLabel.String = [];
            
            cla(app.presence);
            cla(app.future);
            
            app.Start.Enable = 'on';
            
            yao_num = 0;
            setappdata(0, 'yao_num', yao_num);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.presence = uiaxes(app.UIFigure);
            app.presence.XTick = [];
            app.presence.YTick = [];
            app.future = uiaxes(app.UIFigure);
            app.future.XTick = [];
            app.future.YTick = [];
            app.Lamp_Yao_1 = uilamp(app.UIFigure);
            app.Lamp_Yao_2 = uilamp(app.UIFigure);
            app.Lamp_Yao_3 = uilamp(app.UIFigure);
            app.Lamp_Yao_4 = uilamp(app.UIFigure);
            app.Lamp_Yao_5 = uilamp(app.UIFigure);
            app.Lamp_Yao_6 = uilamp(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '挂一 分二 揲四 归奇';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '一变';
            app.Slider_Bian_1 = uislider(app.Panel);
            app.Slider_Bian_1.ValueChangedFcn = createCallbackFcn(app, @Slider_Bian_1ValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '二变';
            app.Slider_Bian_2 = uislider(app.Panel);
            app.Slider_Bian_2.ValueChangedFcn = createCallbackFcn(app, @Slider_Bian_2ValueChanged, true);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '三变';
            app.Slider_Bian_3 = uislider(app.Panel);
            app.Slider_Bian_3.ValueChangedFcn = createCallbackFcn(app, @Slider_Bian_3ValueChanged, true);
            app.Lamp_Bian_1 = uilamp(app.Panel);
            app.Lamp_Bian_2 = uilamp(app.Panel);
            app.Lamp_Bian_3 = uilamp(app.Panel);
            app.Start = uibutton(app.Panel, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Text = '起卦';
            app.End = uibutton(app.Panel, 'push');
            app.End.ButtonPushedFcn = createCallbackFcn(app, @EndPushed, true);
            app.End.Text = '重卜';
        end
    end

    methods (Access = public)

        % Construct app
        function app = YI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end